classdef EqualizerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BrowseButton        matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        Slider_2            matlab.ui.control.Slider
        Label_4             matlab.ui.control.Label
        Slider_3            matlab.ui.control.Slider
        Label_5             matlab.ui.control.Label
        Slider_4            matlab.ui.control.Slider
        Label_6             matlab.ui.control.Label
        Slider_5            matlab.ui.control.Slider
        Label_7             matlab.ui.control.Label
        Slider_6            matlab.ui.control.Slider
        Label_8             matlab.ui.control.Label
        Slider_7            matlab.ui.control.Slider
        Label_9             matlab.ui.control.Label
        Slider_8            matlab.ui.control.Slider
        fileEditFieldLabel  matlab.ui.control.Label
        fileEditField       matlab.ui.control.EditField
        SetZeroGainsButton  matlab.ui.control.Button
        Label_10            matlab.ui.control.Label
        Slider_9            matlab.ui.control.Slider
        Label_11            matlab.ui.control.Label
        Slider_10           matlab.ui.control.Slider
        Label_12            matlab.ui.control.Label
        Slider_11           matlab.ui.control.Slider
        PlayButton          matlab.ui.control.Button
        PauseButton         matlab.ui.control.Button
        StopButton          matlab.ui.control.Button
        FrequencyLabel      matlab.ui.control.Label
        Label_13            matlab.ui.control.Label
        Slider_12           matlab.ui.control.Slider
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            f125 = app.Slider_12.Value ;
            f250 = app.Slider_2.Value ;
            f500 = app.Slider_3.Value ;
            f1000 = app.Slider_4.Value ;
            f2000 = app.Slider_5.Value ;
            f3000 = app.Slider_6.Value ;
            f4000 = app.Slider_7.Value ;
            f6000 = app.Slider_8.Value ;
            f8000 = app.Slider_9.Value ;
            f12000 = app.Slider_10.Value ;
            f20000 = app.Slider_11.Value ;


            plot(app.UIAxes,f125, '-')
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('C:\Users\addep\OneDrive\Dokument\Equalizer-TFYY51\Ljudfiler') ;
            app.fileEditField.Value = file ;
        end

        % Value changed function: fileEditField
        function fileEditFieldValueChanged(app, event)
         
            
        end

        % Button pushed function: SetZeroGainsButton
        function SetZeroGainsButtonPushed(app, event)
           app.Slider_2.Value = 0 ;
           app.Slider_3.Value = 0 ;
           app.Slider_4.Value = 0 ;
           app.Slider_5.Value = 0 ;
           app.Slider_6.Value = 0 ;
           app.Slider_7.Value = 0 ;
           app.Slider_8.Value = 0 ;
           app.Slider_9.Value = 0 ;
           app.Slider_10.Value = 0 ;
           app.Slider_11.Value = 0 ;
           app.Slider_12.Value = 0 ;
           
        end

        % Value changed function: Slider_12
        function Slider_12ValueChanged(app, event)
            value = app.Slider_12.Value;
            plot(app.UIAxes,value,'o',"XData",125);
            app.UIAxes.XLim =[0 20000];
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            plot(app.UIAxes,value,'o',"XData",250);
            app.UIAxes.XLim = [0 20000];
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
         [y, fs]   = audioread('1.1World_of_Goo.wav') ;
         player = audioplayer(y, fs) ;
         play(player) ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '250';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-100 100];
            app.Slider_2.MajorTicks = [-100 0 100];
            app.Slider_2.MajorTickLabels = {'-100', '0', '100'};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '500';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-100 100];
            app.Slider_3.MajorTicks = [-100 0 100];
            app.Slider_3.MinorTicks = [];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '1000';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-100 100];
            app.Slider_4.MajorTicks = [-100 0 100];
            app.Slider_4.MinorTicks = [];
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '2000';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-100 100];
            app.Slider_5.MajorTicks = [-100 0 100];
            app.Slider_5.MinorTicks = [];
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '3000';
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [-100 100];
            app.Slider_6.MajorTicks = [-100 0 100];
            app.Slider_6.MinorTicks = [];
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '4000';
            app.Slider_7 = uislider(app.UIFigure);
            app.Slider_7.Limits = [-100 100];
            app.Slider_7.MajorTicks = [-100 0 100];
            app.Slider_7.MinorTicks = [];
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '6000';
            app.Slider_8 = uislider(app.UIFigure);
            app.Slider_8.Limits = [-100 100];
            app.Slider_8.MajorTicks = [-100 0 100];
            app.Slider_8.MinorTicks = [];
            app.fileEditFieldLabel = uilabel(app.UIFigure);
            app.fileEditFieldLabel.Text = 'file:';
            app.fileEditField = uieditfield(app.UIFigure, 'text');
            app.fileEditField.ValueChangedFcn = createCallbackFcn(app, @fileEditFieldValueChanged, true);
            app.SetZeroGainsButton = uibutton(app.UIFigure, 'push');
            app.SetZeroGainsButton.ButtonPushedFcn = createCallbackFcn(app, @SetZeroGainsButtonPushed, true);
            app.SetZeroGainsButton.Text = 'Set Zero Gains';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '8000';
            app.Slider_9 = uislider(app.UIFigure);
            app.Slider_9.Limits = [-100 100];
            app.Slider_9.MajorTicks = [-100 0 100];
            app.Slider_9.MinorTicks = [];
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '12000';
            app.Slider_10 = uislider(app.UIFigure);
            app.Slider_10.Limits = [-100 100];
            app.Slider_10.MajorTicks = [-100 0 100];
            app.Slider_10.MinorTicks = [];
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '20000';
            app.Slider_11 = uislider(app.UIFigure);
            app.Slider_11.Limits = [-100 100];
            app.Slider_11.MajorTicks = [-100 0 100];
            app.Slider_11.MinorTicks = [];
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency:';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '125';
            app.Slider_12 = uislider(app.UIFigure);
            app.Slider_12.Limits = [-100 100];
            app.Slider_12.MajorTicks = [-100 0 100];
            app.Slider_12.MajorTickLabels = {'-100', '0', '100'};
            app.Slider_12.ValueChangedFcn = createCallbackFcn(app, @Slider_12ValueChanged, true);
            app.Slider_12.MinorTicks = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Frequency (kHz)')
            ylabel(app.UIAxes, 'Gain (dB)')
            app.UIAxes.XLim = [0 20000];
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [0 125 250 500 1000 2000 3000 4000 6000 8000 12000 20000];
            app.UIAxes.XTickLabel = {'0'; '125'; '250'; '500'; '1000'; '2000'; '3000'; '4000'; '6000'; '8000'; '12000'; '20000'};
            app.UIAxes.YTick = [-100 -50 0 50 100];
            app.UIAxes.YTickLabel = {'-100'; '-50'; '0'; '50'; '100'};
            app.UIAxes.XScale = 'log';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EqualizerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end